classdef AdjustLabelMask < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        DisplayLabel             matlab.ui.control.Label
        ControlsLabel            matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        ActivateeditmodeHold15anddrawLabel  matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        FinalizeLabel            matlab.ui.control.Label
        LoadButton               matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        SavetestButton           matlab.ui.control.Button
        SavetrainButton          matlab.ui.control.Button
        LabelColors              matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
        MaskalphaEditField       matlab.ui.control.NumericEditField
        MaskalphaEditFieldLabel  matlab.ui.control.Label
        EditmodeButton           matlab.ui.control.StateButton
        SpotsizeSpinner          matlab.ui.control.Spinner
        SpotsizeSpinnerLabel     matlab.ui.control.Label
        MainImageAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        labim % Description
        MoveScreen % Description
        CP % Description
        cmap % Description
        labs % Description
        im % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, im, labs)
            
            if nargin < 2
                im = 0;
            end
            if nargin < 3
                labs = zeros(size(im));
            end
            app.im = im;
            app.labs = labs;
            imshow(real2rgb(im,'gray'),'Parent',app.MainImageAxes);
            hold(app.MainImageAxes,'on');
            app.labim = imagesc(labs,'Parent',app.MainImageAxes);
            app.labim.AlphaData = app.MaskalphaEditField.Value;
            app.MainImageAxes.CLim = [0 5];
            app.UITable.Data = ["1" "1" "0" "0"; "2" "0" "1" "0";...
                "3" "0" "0" "1"; "4" "1" "1" "0"; "5" "1" "0" "1"];
            app.MainImageAxes.Colormap = str2double(app.UITable.Data(:,2:end));
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = 2*event.VerticalScrollCount;
            
            xlims1 = get(app.MainImageAxes,'xlim');
            ylims1 = get(app.MainImageAxes,'ylim');
            
            app.MainImageAxes.XLim = [xlims1(1) - verticalScrollCount...
                xlims1(2) + verticalScrollCount];
            app.MainImageAxes.YLim = [ylims1(1) - verticalScrollCount...
                ylims1(2) + verticalScrollCount];
            
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            keynum = str2double(key);
            if key == "m"
                app.MoveScreen = true;
                app.CP = get(app.MainImageAxes,'CurrentPoint');
            elseif ~isnan(keynum) && app.EditmodeButton.Value
                C = get(app.MainImageAxes,'CurrentPoint');
                tempmask = zeros(size(app.labs));
                tempmask(round(C(1,2)),round(C(1,1))) = 1;
                tempmask = imdilate(tempmask,... 
                        strel('disk',app.SpotsizeSpinner.Value,8));
                app.labs(tempmask==1) = keynum;
                app.labim.CData = app.labs;
            end
                
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            if key == "m"
                app.MoveScreen = false;
            end
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if app.MoveScreen
                C = get(app.MainImageAxes,'CurrentPoint');
                dx = C(1,1)-app.CP(1,1);
                dy = C(1,2)-app.CP(1,2);
                xlims1 = get(app.MainImageAxes,'XLim');
                ylims1 = get(app.MainImageAxes,'YLim');
                app.MainImageAxes.XLim = xlims1-dx;
                app.MainImageAxes.YLim = ylims1-dy;
                app.CP = get(app.MainImageAxes,'CurrentPoint');
            end
        end

        % Value changed function: MaskalphaEditField
        function MaskalphaEditFieldValueChanged(app, event)
            value = app.MaskalphaEditField.Value;
            app.labim.AlphaData = value;
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            
            Data = str2double(app.UITable.Data);
            app.MainImageAxes.Colormap = Data(:,2:end);
            
        end

        % Button pushed function: SavetrainButton
        function SavetrainButtonPushed(app, event)
            fpath = uigetdir;
            mkdir(fullfile(fpath,'train'));
            mkdir(fullfile(fpath,'train','msk'));
            mkdir(fullfile(fpath,'train','img'));
            i = 1;
            l = max(app.labs,[],'all');
            fname = sprintf('im%03d_j%d.png',i,l);
            imfname = sprintf('im%03d.png',i);
            while exist(fullfile(fpath,'train','msk',fname))
                i = i+1;
                fname = sprintf('im%03d_j%d.png',i,l);
                imfname = sprintf('im%03d.png',i);
            end
            imwrite(app.labs,fullfile(fpath,'train','msk',fname));
            imwrite(app.im,fullfile(fpath,'train','img',imfname));
            
        end

        % Button pushed function: SavetestButton
        function SavetestButtonPushed(app, event)
            fpath = uigetdir;
            mkdir(fullfile(fpath,'test'));
            mkdir(fullfile(fpath,'test','msk'));
            mkdir(fullfile(fpath,'test','img'));
            i = 1;
            l = max(app.labs,[],'all');
            fname = sprintf('im%03d_j%d.png',i,l);
            imfname = sprintf('im%03d.png',i);
            while exist(fullfile(fpath,'test','msk',fname))
                i = i+1;
                fname = sprintf('im%03d_j%d.png',i,l);
                imfname = sprintf('im%03d.png',i);
            end
            imwrite(app.labs,fullfile(fpath,'test','msk',fname));
            imwrite(app.im,fullfile(fpath,'img',imfname));
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
            app.MainImageAxes.Position = [151 1 position(3) position(4)];
            app.Panel.Position = [1 position(4)-480 150 480];
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [fname, ffldr] = uiputfile ({'*.mat', 'MatLab Data Files'}, 'Choose savefile path', 'LabelMask.mat');
            im = app.im; 
            labs = app.labs;
            save (fullfile (ffldr, fname), 'im', 'labs');
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [fname, ffldr] = uigetfile ({'*.mat', 'MatLab data files'}, ...
                'Select file to load', 'LabelMask.mat');
            fpath = fullfile (ffldr, fname);
            if not (exist (fpath, 'file'))
                warndlg ('selected file does not exist')
                return
            end
            data = load(fpath,'im','labs');
            app.im = data.im;
            app.labs = data.labs;
            imshow(real2rgb(app.im,'gray'),'Parent',app.MainImageAxes);
            hold(app.MainImageAxes,'on');
            app.labim = imagesc(app.labs,'Parent',app.MainImageAxes);
            app.labim.AlphaData = app.MaskalphaEditField.Value;
            app.MainImageAxes.CLim = [0 5];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.MainImageAxes = uiaxes(app.UIFigure);
            app.MainImageAxes.XTick = [];
            app.MainImageAxes.YTick = [];
            app.MainImageAxes.Visible = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.SpotsizeSpinnerLabel = uilabel(app.Panel);
            app.SpotsizeSpinnerLabel.Text = 'Spot size';
            app.SpotsizeSpinner = uispinner(app.Panel);
            app.SpotsizeSpinner.Editable = 'off';
            app.SpotsizeSpinner.Value = 3;
            app.EditmodeButton = uibutton(app.Panel, 'state');
            app.EditmodeButton.Text = 'Edit mode';
            app.MaskalphaEditFieldLabel = uilabel(app.Panel);
            app.MaskalphaEditFieldLabel.Text = 'Mask alpha';
            app.MaskalphaEditField = uieditfield(app.Panel, 'numeric');
            app.MaskalphaEditField.ValueChangedFcn = createCallbackFcn(app, @MaskalphaEditFieldValueChanged, true);
            app.MaskalphaEditField.Value = 0.2;
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'Label'; 'R'; 'G'; 'B'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.LabelColors = uilabel(app.Panel);
            app.LabelColors.Text = 'Label Colors:';
            app.SavetrainButton = uibutton(app.Panel, 'push');
            app.SavetrainButton.ButtonPushedFcn = createCallbackFcn(app, @SavetrainButtonPushed, true);
            app.SavetrainButton.Text = 'Save train';
            app.SavetestButton = uibutton(app.Panel, 'push');
            app.SavetestButton.ButtonPushedFcn = createCallbackFcn(app, @SavetestButtonPushed, true);
            app.SavetestButton.Text = 'Save test';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.Panel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.FinalizeLabel = uilabel(app.Panel);
            app.FinalizeLabel.Text = 'Finalize:';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '_________________________';
            app.ActivateeditmodeHold15anddrawLabel = uilabel(app.Panel);
            app.ActivateeditmodeHold15anddrawLabel.Text = {'Activate edit mode.'; 'Hold 1-5 and draw.'};
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '_________________________';
            app.ControlsLabel = uilabel(app.Panel);
            app.ControlsLabel.Text = 'Controls:';
            app.DisplayLabel = uilabel(app.Panel);
            app.DisplayLabel.Text = 'Display:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AdjustLabelMask(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end