classdef spineLabeler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SmoothnessEditFieldLabel      matlab.ui.control.Label
        SmoothnessEditField           matlab.ui.control.NumericEditField
        FindCellsParametersLabel      matlab.ui.control.Label
        MaxLengthpixEditFieldLabel    matlab.ui.control.Label
        MaxLengthpixEditField         matlab.ui.control.NumericEditField
        MinLengthpixEditFieldLabel    matlab.ui.control.Label
        MinLengthpixEditField         matlab.ui.control.NumericEditField
        BrightnessThresholdEditFieldLabel  matlab.ui.control.Label
        BrightnessThresholdEditField  matlab.ui.control.NumericEditField
        FindCellsButton               matlab.ui.control.Button
        NextCell                      matlab.ui.control.Button
        ApproveCurrentCell            matlab.ui.control.Button
        SmoothingScaleEditFieldLabel  matlab.ui.control.Label
        SmoothingScaleEditField       matlab.ui.control.NumericEditField
        RefineManuallyButton          matlab.ui.control.Button
        BusyLampLabel                 matlab.ui.control.Label
        BusyLamp                      matlab.ui.control.Lamp
        SubsampleEditFieldLabel       matlab.ui.control.Label
        SubsampleEditField            matlab.ui.control.NumericEditField
        ExpandCropButton              matlab.ui.control.Button
        SmoothStreamlineButton        matlab.ui.control.Button
        ResetStreamlineButton         matlab.ui.control.Button
        CropPaddingEditFieldLabel     matlab.ui.control.Label
        CropPaddingEditField          matlab.ui.control.NumericEditField
        ApprovedLampLabel             matlab.ui.control.Label
        ApprovedLamp                  matlab.ui.control.Lamp
        LoadExistingButton            matlab.ui.control.Button
        PrevCell                      matlab.ui.control.Button
        NextUnapprovedButton          matlab.ui.control.Button
        ShowApprovedCellsCheckBox     matlab.ui.control.CheckBox
        UnapproveCurrentCell          matlab.ui.control.Button
        CurrentCellAxes               matlab.ui.control.UIAxes
        CenterPanel                   matlab.ui.container.Panel
        MainImageAxes                 matlab.ui.control.UIAxes
        ApprovedImageAxes             matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        SaveDataButton                matlab.ui.control.Button
        NumApprovedEditFieldLabel     matlab.ui.control.Label
        NumApprovedEditField          matlab.ui.control.NumericEditField
        TotalNumCellsEditFieldLabel   matlab.ui.control.Label
        TotalNumCellsEditField        matlab.ui.control.NumericEditField
        AddNewCellButton              matlab.ui.control.Button
        EnlargeFindCellsResultButton  matlab.ui.control.Button
        EnlargeApprovedCellsButton    matlab.ui.control.Button
        GotoCellButton                matlab.ui.control.Button
        ShowExistingCheckBox          matlab.ui.control.CheckBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        Image = []; % main image
        Mask = [];  % cell mask (1 => cells)
        CellList = [];     % list generated by `findcells`
        % struct that holds current cell data
        CurrentCellData = struct ('id', nan, ...
                'crop_box', nan(1,4), ...
                'refined', nan(2,1), ...
                'init', nan (2,1), ...
                'approved', false);
    end
    
    methods (Access = private)
        
        function drawImage(app, plot_boundary)
            if nargin == 1 
                plot_boundary = true;
            end
            % show image and mask boundaries
            imshow (app.Image, 'Parent', app.MainImageAxes);
            set (app.MainImageAxes, 'XLimSpec', 'Tight')
            set (app.MainImageAxes, 'YLimSpec', 'Tight')
            if plot_boundary
                % show mask boundaries
                B = bwboundaries (app.Mask);
                hold (app.MainImageAxes, 'on')
                for ib = 1:numel(B)
                    bound = B{ib};
                    plot (app.MainImageAxes, bound(:,2), bound(:,1), 'k', ...
                        'LineWidth', 1)
                end
                hold (app.MainImageAxes, 'off')
            end
        end
        
        function clearApprovedCells(app)
            for ci = 1:numel(app.CellList)
                app.CellList(ci).approved = false;
            end
            app.drawApprovedCells();
        end
        
        function loadNewCurrentCell (app, id)
            idx = app.foundCellIndexForId (id);
            app.CurrentCellData = app.CellList(idx);
            app.drawCurrentCell();
            if (app.CurrentCellData.approved)
                app.ApprovedLamp.Color = [0 1 0];
            else
                app.ApprovedLamp.Color = [1 0 0];
            end
        end
        
        function drawCurrentCell (app)
            % draw the cell image
            crop_box = app.CurrentCellData.crop_box;
            cla (app.CurrentCellAxes, 'reset');
            imshow (imcrop (app.Image, crop_box), 'Parent', app.CurrentCellAxes);
            set (app.CurrentCellAxes, 'XLimSpec', 'Tight')
            xlim = get (app.CurrentCellAxes, 'XLim');
            set (app.CurrentCellAxes, 'YLimSpec', 'Tight')
            ylim = get (app.CurrentCellAxes, 'YLim');
            % plot the streamline
            strlne = app.CurrentCellData.refined;
            lne_crp = app.global2CropCoords (strlne, app.CurrentCellData.crop_box);
            hold (app.CurrentCellAxes, 'on')
            plot (app.CurrentCellAxes, lne_crp(:,1), lne_crp(:,2), 'b')
            hold (app.CurrentCellAxes, 'off');
            if (app.ShowApprovedCellsCheckBox.Value)
                meanpos = @(c) mean (c.refined);
                % plot nearby approved cell contours
                apr = app.CellList(arrayfun (@(x) x.approved, app.CellList));
                if not (isempty (apr))
                    muz = arrayfun (meanpos, apr, 'UniformOutput', false);
                    mu = meanpos (app.CurrentCellData);
                    dist = cellfun (@(g) sqrt (sum ((g-mu).^2)), muz);
                    to_plot = apr(dist < 300 & dist > 1);
                    hold (app.CurrentCellAxes, 'on')
                    for pii = 1:length(to_plot)
                        coords = app.global2CropCoords (to_plot(pii).refined, ...
                            app.CurrentCellData.crop_box);
                        plot (app.CurrentCellAxes, coords(:,1), coords(:,2), 'r')
                    end
                    hold (app.CurrentCellAxes, 'off')
                end
            end
            set (app.CurrentCellAxes, 'XLim', xlim);
            set (app.CurrentCellAxes, 'YLim', ylim);
        end
        
        function setBusyLamp (app, is_busy)
            if is_busy
                app.BusyLamp.Color = [0 1 0];
            else
                app.BusyLamp.Color = [0.86 0.86 0.86];
            end
            drawnow;
        end
        
        function drawApprovedCells(app)
            % show image
            imshow (app.Image, 'Parent', app.ApprovedImageAxes);
            imagesc (app.ApprovedImageAxes, app.Image);
            colormap (app.ApprovedImageAxes, 'gray');
            set (app.ApprovedImageAxes, 'XLimSpec', 'Tight')
            set (app.ApprovedImageAxes, 'YLimSpec', 'Tight')
            % loop through found cells, only plotting approved
            hold (app.ApprovedImageAxes, 'on')
            for idx = 1:length(app.CellList)
                this = app.CellList(idx);
                if not (this.approved), continue, end
                plot (app.ApprovedImageAxes, ...
                    this.refined(:,1), this.refined(:,2))
            end
            hold (app.ApprovedImageAxes, 'off')
        end
        
        function drawApprovedCell (app, idx)
            if not (app.CellList(idx).approved)
                warndlg ('cell isnt approved')
                return
            end
            hold (app.ApprovedImageAxes, 'on')
            this = app.CellList(idx);
            plot (app.ApprovedImageAxes, ...
                  this.refined(:,1), this.refined(:,2))
            hold (app.ApprovedImageAxes, 'off')
        end
        
        function idx = foundCellIndexForId(app, id)
            idx = find (arrayfun (@(x) x.id == id, app.CellList), 1);
        end
        
        
        function [next_id, idx] = getNextUnapproved(app)
            % get id's and cells that aren't approved
            idz = arrayfun (@(x) x.id, app.CellList);
            npr = not (arrayfun (@(x) x.approved, app.CellList));
            % figure out which cell to look at next
            next_id = find (idz > app.CurrentCellData.id & npr, 1, 'first');
            if isempty (next_id)
                next_id = min (idz(npr));
            end
            if not (isempty (next_id))
                idx = find (idz == next_id, 1, 'first');
            else
                idx = [];
            end
        end
        
        function drawFoundCellStreamlines (app, plot_boundary)
            app.drawImage (plot_boundary);
            hold (app.MainImageAxes, 'on')
            for ic = 1:length(app.CellList)
                A = app.CellList(ic).init;
                plot (app.MainImageAxes, A(:,1), A(:,2))
            end
            hold (app.MainImageAxes, 'off')
        end
        
        
        function crp = global2CropCoords (app, glb, rect)
            sze = size (imcrop (app.Image, rect));
            % how much does actual crop size vary from specified?
            dxy = sze([2 1]) - rect([3 4]);
            crp = glb - rect([1 2]) + dxy;
        end
        
        function glb = crop2GlobalCoords (app, crp, rect)
            sze = size (imcrop (app.Image, rect));
            dxy = sze([2 1]) - rect([3 4]);
            glb = crp + rect([1 2]) - dxy;
        end
        
        function data = cellDataFromStreamline (app, id, pix)
            % figure out cell ends
            xse = pix([1 end], 1);
            yse = pix([1 end], 2);
            % crop image and display
            crop_box = cropBoxFromCellEnds (xse, yse, 20, true, ...
                size (app.Image));
            % crop label mask and display boundary
            data = struct ('id', id, ...
                'crop_box', crop_box, ...
                'init', pix, ...
                'refined', pix, ...
                'approved', false);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, img, mask)
            % add supplement folder to path
            fpath = mfilename ('fullpath');
            [this_fldr, ~, ~] = fileparts (fpath);
            addpath (fullfile (this_fldr, 'supp'));
            
            % input validation
            assert (length (size (img)) == 2, 'img must be 2d array')
            assert (all (size (img) == size (mask)), ...
                'input image and mask must be same size')
            if not (isa (img, 'double'))
                img = im2double (img);
            end
            if not (islogical (mask))
               mask = not (mask == 0);
            end
            app.Image = normalizeImage (img);
            app.Mask = mask;
            % draw image
            app.drawImage (true);
            app.setBusyLamp (false);
        end

        % Button pushed function: FindCellsButton
        function FindCellsButtonPushed(app, event)
            n_approved = sum (arrayfun (@(x) x.approved, app.CellList));
            % make sure user wants to proceed...
            if n_approved > 0
                answer = questdlg (...
                    "Re-finding cells will clear all progress. Proceed?", ...
                    "Restart approval?", "yes", "no", "no");
                if strcmp (answer, "no") % they don't
                    return
                end
            end

            % if we got here, we're good to go
            app.clearApprovedCells();
            
            app.setBusyLamp(true);
            % run findcells and plot answer
            cellz = findcells (app.Image, not (app.Mask), ...
                app.SmoothnessEditField.Value, ...
                app.MaxLengthpixEditField.Value, ...
                app.MinLengthpixEditField.Value, ...
                app.BrightnessThresholdEditField.Value);
            % sort by x/y distance from (0,0)
            function [dist] = meanXYdist (c)
               meanpos = mean (c.pix, 1);
               dist = sqrt (sum (meanpos.^2));
            end
            ds = arrayfun (@meanXYdist, cellz);
            [~, idx] = sort (ds);
            cellz = cellz(idx);
            % assign ids to cells
            for ic = 1:numel(cellz)
                cellz(ic).id = ic;
            end
            % generate list of found cells
            app.CellList = arrayfun (...
                @(c) app.cellDataFromStreamline (c.id, c.pix), ...
                cellz);
            % load first cell as current
            app.loadNewCurrentCell(1);
            app.TotalNumCellsEditField.Value = length (app.CellList);
            app.NumApprovedEditField.Value = 0;
            app.drawFoundCellStreamlines (true);
            app.setBusyLamp(false);
        end

        % Button pushed function: RefineManuallyButton
        function RefineManuallyButtonPushed(app, event)
            if isnan (app.CurrentCellData.id)
                warndlg ('no cell to refine')
                return
            end
            % show big image to do adjustment in
            fig = figure(1);
            axes (fig.CurrentAxes, 'Units', 'normalize', 'Position', [0 0 1 1])
            imshow (imcrop (app.Image, app.CurrentCellData.crop_box));
            fig.WindowState = 'maximized';
            % draw polygon and wait for user to double-click to confirm
            skip = app.SubsampleEditField.Value;
            if (skip > 0)
                strlne = app.CurrentCellData.refined(1:skip:end,:);
            else
                strlne = app.CurrentCellData.refined;
            end
            strlne = app.global2CropCoords (strlne, app.CurrentCellData.crop_box);
            if (app.ShowApprovedCellsCheckBox.Value)
                glbMeanPos = @(c) mean (c.refined);
                % plot nearby approved cell contours
                apr = app.CellList(arrayfun (@(x) x.approved, app.CellList));
                if not (isempty (apr))
                    glb_muz = arrayfun (glbMeanPos, apr, 'UniformOutput', false);
                    mu = glbMeanPos (app.CurrentCellData);
                    dist = cellfun (@(g) sqrt (sum ((g-mu).^2)), glb_muz);
                    to_plot = apr(dist < 300 & dist > 1);
                    hold (fig.CurrentAxes, 'on')
                    for pii = 1:length(to_plot)
                        coords = app.global2CropCoords (to_plot(pii).refined, ...
                            app.CurrentCellData.crop_box);
                        plot (fig.CurrentAxes, coords(:,1), coords(:,2), 'r')
                    end
                    hold (fig.CurrentAxes, 'off')
                end
            end
            h = drawpolyline (fig.CurrentAxes, 'Position', strlne);
            function clickCallback (~, evt)
                if strcmp (evt.SelectionType, 'double')
                    uiresume;
                end
            end
            function [pos] = customWait (hROI)
                list = addlistener (hROI, 'ROIClicked', @clickCallback);
                uiwait;
                delete (list);
                try, pos = hROI.Position;  % throws if user x'd out window
                catch, pos = [];
                end
            end
            roi = customWait (h);
            if ~isempty (roi)
                % convert back out to global
                app.CurrentCellData.refined = app.crop2GlobalCoords (...
                    roi, app.CurrentCellData.crop_box);
            end
            app.drawCurrentCell();
            try, close (fig);  % try/catch is for if already deleted
            catch, end
        end

        % Button pushed function: ApproveCurrentCell
        function ApproveCurrentCellButtonPushed(app, event)
            % add to list of approved cells
            app.CurrentCellData.approved = true;
            curr_idx = find (arrayfun (@(x) x.id, app.CellList) == app.CurrentCellData.id, 1);
            app.CellList(curr_idx) = app.CurrentCellData;
            [id, ~] = app.getNextUnapproved();
            app.loadNewCurrentCell (id);
            n_approved = sum (arrayfun (@(x) x.approved, app.CellList));
            if n_approved > 1
                app.drawApprovedCell (curr_idx);
            else
                app.drawApprovedCells ();
            end
            % increment approved counter
            app.NumApprovedEditField.Value = n_approved;
            drawnow;
        end

        % Button pushed function: NextCell
        function NextCellButtonPushed(app, event)
            idz = arrayfun (@(x) x.id, app.CellList);
            idx = find (idz > app.CurrentCellData.id, 1, 'first');
            if isempty (idx)
                warndlg ('No next cell, at end of list');
                return
            end
            app.loadNewCurrentCell (idz(idx));
        end

        % Button pushed function: ExpandCropButton
        function ExpandCropButtonPushed(app, event)
            [y_sze, x_sze] = size (app.Image);
            pad = app.CropPaddingEditField.Value;
            curr_box = app.CurrentCellData.crop_box;
            new_box = curr_box;
            new_box(1) = max (curr_box(1)-pad, 0);
            new_box(2) = max (curr_box(2)-pad, 0);
            actual_pads = curr_box(1:2) - new_box(1:2);
            cand_wid = curr_box(3) + actual_pads(1)*2;
            cand_hgt = curr_box(4) + actual_pads(2)*2;
            if (curr_box(1) + cand_wid > x_sze)
                new_box(3) = x_sze - curr_box(1);
            else
                new_box(3) = cand_wid;
            end
            if (curr_box(2) + cand_hgt > y_sze)
                new_box(4) = y_sze - curr_box(2);
            else
                new_box(4) = cand_hgt;
            end
            app.CurrentCellData.crop_box = new_box;
            app.drawCurrentCell();
        end

        % Button pushed function: SmoothStreamlineButton
        function SmoothStreamlineButtonPushed(app, event)
            if isnan (app.CurrentCellData.id)
                warndlg ('no cell contour to smooth')
                return
            end
            app.setBusyLamp (true);
            smth = smoothContour (app.CurrentCellData.refined, ...
                app.SmoothingScaleEditField.Value);
            app.CurrentCellData.refined = smth;
            app.drawCurrentCell();
            app.setBusyLamp (false);
        end

        % Button pushed function: ResetStreamlineButton
        function ResetStreamlineButtonPushed(app, event)
            app.CurrentCellData.refined = app.CurrentCellData.init;
            app.drawCurrentCell();
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            [fname, ffldr] = uiputfile ({'*.mat', 'MatLab Data Files'}, 'Choose savefile path', 'cellmask.mat');
            Image = app.Image; Mask = app.Mask;
            cellList = app.CellList;
            save (fullfile (ffldr, fname), 'Image', 'Mask', 'cellList');
        end

        % Button pushed function: PrevCell
        function PrevCellButtonPushed(app, event)
            idz = arrayfun (@(x) x.id, app.CellList);
            idx = find (idz < app.CurrentCellData.id, 1, 'last');
            if isempty (idx)
                warndlg ('no previous cell, at start of list');
                return
            end
            app.loadNewCurrentCell (idz(idx));
        end

        % Button pushed function: NextUnapprovedButton
        function NextUnapprovedButtonPushed(app, event)
            [next_id, ~] = app.getNextUnapproved();
            app.loadNewCurrentCell (next_id);
        end

        % Button pushed function: LoadExistingButton
        function LoadExistingButtonPushed(app, event)
            [fname, ffldr] = uigetfile ({'*.mat', 'MatLab data files'}, ...
                'Select file to load', 'cellmask.mat');
            fpath = fullfile (ffldr, fname);
            if not (exist (fpath, 'file'))
                warndlg ('selected file does not exist')
                return
            end
            answer = questdlg ('Keep current image or load from existing?', ...
                'Default Parameters', 'current', 'existing', 'current');
            props = load (fpath);
            % update properties
            if strcmp (answer, 'existing')
                app.Image = props.Image;
            end
            app.Mask = props.Mask;
            app.CellList = props.cellList;
            % use properties to update UI
            app.drawImage (false);
            app.drawFoundCellStreamlines (true);
            app.drawApprovedCells();
            % find min unapproved cell id and set as current
            apr = arrayfun (@(x) x.approved, app.CellList);
            npr_idx = find (not (apr), 1, 'first');
            if isempty (npr_idx)
                warndlg ('didnt find an unapproved cell')
                npr_idx = 1;
            end
            app.loadNewCurrentCell (app.CellList(npr_idx).id);
            % update counters
            app.TotalNumCellsEditField.Value = length (apr);
            app.NumApprovedEditField.Value = sum (apr);
        end

        % Button pushed function: AddNewCellButton
        function AddNewCellButtonPushed(app, event)
            fig = figure(1);
            imshow (app.Image);
            if (app.ShowExistingCheckBox.Value)
                % plot existing cells on top
                hold ('on')
                for idx = 1:length(app.CellList)
                    if app.CellList(idx).approved
                        plot (app.CellList(idx).refined(:,1), ...
                            app.CellList(idx).refined(:,2));
                    end
                end
                hold ('off')
            end
            [xse, yse] = ginput (2);
            crop_box = cropBoxFromCellEnds(xse, yse, 20, true, ...
                size (app.Image));
            % draw cell streamline
            Icrop = imcrop (app.Image, crop_box);
            imshow (Icrop);
            lne = drawpolyline (gca);
            new_id = max (arrayfun (@(x) x.id, app.CellList)) + 1;
            glb = app.crop2GlobalCoords (lne.Position, crop_box);
            % create cell data
            celldata = app.cellDataFromStreamline (new_id, glb);
            % add to celllist and load for refinement
            app.CellList(end+1) = celldata;
            app.loadNewCurrentCell(new_id);
            app.TotalNumCellsEditField.Value = app.TotalNumCellsEditField.Value + 1;
            close (fig)
        end

        % Button pushed function: EnlargeFindCellsResultButton
        function EnlargeFindCellsResultButtonPushed(app, event)
            % show results of findcells
            imshow (app.Image)
            hold ('on')
            for ic = 1:length(app.CellList)
                A = app.CellList(ic).init;
                plot (A(:,1), A(:,2))
            end
            hold ('off')
        end

        % Button pushed function: EnlargeApprovedCellsButton
        function EnlargeApprovedCellsButtonPushed(app, event)
            % show approved cell streamlines
            imshow (app.Image)
            hold ('on')
            for ic = 1:length(app.CellList)
                this = app.CellList(ic);
                if (this.approved)
                    plot (this.refined(:,1), this.refined(:,2))
                end
            end
            hold ('off')
        end

        % Button pushed function: UnapproveCurrentCell
        function UnapproveCurrentCellButtonPushed(app, event)
            % add to list of approved cells
            app.CurrentCellData.approved = false;
            curr_idx = find (arrayfun (@(x) x.id, app.CellList) == app.CurrentCellData.id, 1);
            app.CellList(curr_idx) = app.CurrentCellData;
            [id, ~] = app.getNextUnapproved();
            app.loadNewCurrentCell (id);
            n_approved = sum (arrayfun (@(x) x.approved, app.CellList));
            if n_approved > 1
                app.drawApprovedCell (curr_idx);
            else
                app.drawApprovedCells ();
            end
            % increment approved counter
            app.NumApprovedEditField.Value = n_approved;
            drawnow;
        end

        % Button pushed function: GotoCellButton
        function GotoCellButtonPushed(app, event)
            % get approved cells
            apr = app.CellList(arrayfun (@(x) x.approved, app.CellList));
            if isempty (apr)
                warndlg ('no approved cells to go to');
                return
            end
            % show approved cell contours
            fig = figure(1);
            imshow (app.Image);
            hold ('on')
            for ic = 1:length(apr)
                plot (apr(ic).refined(:,1), apr(ic).refined(:,2))
            end
            hold ('off')
            % get user input
            [xm, ym] = ginput (1);
            click_loc = [xm, ym];
            % compute mean position of contour for each cell
            mean_pos = arrayfun (@(a) mean (a.refined), apr, ...
                'UniformOutput', false);
            % "correct" cell is the closest one to mouse click
            dist_from_click = cellfun (@(a) sqrt (sum ((a-click_loc).^2)), ...
                mean_pos);
            apr_idx = find (dist_from_click == min (dist_from_click), 1);
            app.loadNewCurrentCell (apr(apr_idx).id);
            close (fig);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {567, 567, 567};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {567, 567};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {313, '1x', 223};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SmoothnessEditFieldLabel = uilabel(app.LeftPanel);
            app.SmoothnessEditFieldLabel.Text = 'Smoothness';
            app.SmoothnessEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SmoothnessEditField.Value = 5;
            app.FindCellsParametersLabel = uilabel(app.LeftPanel);
            app.FindCellsParametersLabel.Text = 'FindCells Parameters';
            app.MaxLengthpixEditFieldLabel = uilabel(app.LeftPanel);
            app.MaxLengthpixEditFieldLabel.Text = 'Max. Length (pix)';
            app.MaxLengthpixEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MaxLengthpixEditField.Value = 80;
            app.MinLengthpixEditFieldLabel = uilabel(app.LeftPanel);
            app.MinLengthpixEditFieldLabel.Text = 'Min. Length (pix)';
            app.MinLengthpixEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MinLengthpixEditField.Value = 10;
            app.BrightnessThresholdEditFieldLabel = uilabel(app.LeftPanel);
            app.BrightnessThresholdEditFieldLabel.Text = 'Brightness Threshold';
            app.BrightnessThresholdEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BrightnessThresholdEditField.Value = 0.5;
            app.FindCellsButton = uibutton(app.LeftPanel, 'push');
            app.FindCellsButton.ButtonPushedFcn = createCallbackFcn(app, @FindCellsButtonPushed, true);
            app.FindCellsButton.Text = 'Find Cells';
            app.NextCell = uibutton(app.LeftPanel, 'push');
            app.NextCell.ButtonPushedFcn = createCallbackFcn(app, @NextCellButtonPushed, true);
            app.NextCell.Text = 'Next';
            app.ApproveCurrentCell = uibutton(app.LeftPanel, 'push');
            app.ApproveCurrentCell.ButtonPushedFcn = createCallbackFcn(app, @ApproveCurrentCellButtonPushed, true);
            app.ApproveCurrentCell.Text = 'Approve';
            app.SmoothingScaleEditFieldLabel = uilabel(app.LeftPanel);
            app.SmoothingScaleEditFieldLabel.Text = 'Smoothing Scale';
            app.SmoothingScaleEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SmoothingScaleEditField.Limits = [-1 Inf];
            app.RefineManuallyButton = uibutton(app.LeftPanel, 'push');
            app.RefineManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @RefineManuallyButtonPushed, true);
            app.RefineManuallyButton.Text = 'Refine Manually';
            app.BusyLampLabel = uilabel(app.LeftPanel);
            app.BusyLampLabel.Text = 'Busy';
            app.BusyLamp = uilamp(app.LeftPanel);
            app.SubsampleEditFieldLabel = uilabel(app.LeftPanel);
            app.SubsampleEditFieldLabel.Text = 'Subsample';
            app.SubsampleEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SubsampleEditField.Limits = [1 Inf];
            app.SubsampleEditField.Value = 1;
            app.ExpandCropButton = uibutton(app.LeftPanel, 'push');
            app.ExpandCropButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandCropButtonPushed, true);
            app.ExpandCropButton.Text = 'Expand Crop';
            app.SmoothStreamlineButton = uibutton(app.LeftPanel, 'push');
            app.SmoothStreamlineButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothStreamlineButtonPushed, true);
            app.SmoothStreamlineButton.Text = 'Smooth Streamline';
            app.ResetStreamlineButton = uibutton(app.LeftPanel, 'push');
            app.ResetStreamlineButton.ButtonPushedFcn = createCallbackFcn(app, @ResetStreamlineButtonPushed, true);
            app.ResetStreamlineButton.Text = 'Reset Streamline';
            app.CropPaddingEditFieldLabel = uilabel(app.LeftPanel);
            app.CropPaddingEditFieldLabel.Text = 'Crop Padding';
            app.CropPaddingEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CropPaddingEditField.Value = 5;
            app.ApprovedLampLabel = uilabel(app.LeftPanel);
            app.ApprovedLampLabel.Text = 'Approved';
            app.ApprovedLamp = uilamp(app.LeftPanel);
            app.LoadExistingButton = uibutton(app.LeftPanel, 'push');
            app.LoadExistingButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExistingButtonPushed, true);
            app.LoadExistingButton.Text = 'Load Existing';
            app.PrevCell = uibutton(app.LeftPanel, 'push');
            app.PrevCell.ButtonPushedFcn = createCallbackFcn(app, @PrevCellButtonPushed, true);
            app.PrevCell.Text = 'Prev.';
            app.NextUnapprovedButton = uibutton(app.LeftPanel, 'push');
            app.NextUnapprovedButton.ButtonPushedFcn = createCallbackFcn(app, @NextUnapprovedButtonPushed, true);
            app.NextUnapprovedButton.Text = 'Next Unapproved';
            app.ShowApprovedCellsCheckBox = uicheckbox(app.LeftPanel);
            app.ShowApprovedCellsCheckBox.Text = 'Show Approved Cells';
            app.ShowApprovedCellsCheckBox.Value = true;
            app.UnapproveCurrentCell = uibutton(app.LeftPanel, 'push');
            app.UnapproveCurrentCell.ButtonPushedFcn = createCallbackFcn(app, @UnapproveCurrentCellButtonPushed, true);
            app.UnapproveCurrentCell.Text = 'Unapprove';
            app.CurrentCellAxes = uiaxes(app.LeftPanel);
            title(app.CurrentCellAxes, 'Current Cell')
            app.CurrentCellAxes.XTick = [];
            app.CurrentCellAxes.YTick = [];
            app.CurrentCellAxes.ZTick = [];
            app.CenterPanel = uipanel(app.GridLayout);
            app.MainImageAxes = uiaxes(app.CenterPanel);
            title(app.MainImageAxes, 'Original Image')
            app.MainImageAxes.XTick = [];
            app.MainImageAxes.YTick = [];
            app.ApprovedImageAxes = uiaxes(app.CenterPanel);
            title(app.ApprovedImageAxes, 'Approved Cells')
            app.ApprovedImageAxes.XTick = [];
            app.ApprovedImageAxes.YTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.SaveDataButton = uibutton(app.RightPanel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.NumApprovedEditFieldLabel = uilabel(app.RightPanel);
            app.NumApprovedEditFieldLabel.Text = 'Num. Approved';
            app.NumApprovedEditField = uieditfield(app.RightPanel, 'numeric');
            app.NumApprovedEditField.Editable = 'off';
            app.TotalNumCellsEditFieldLabel = uilabel(app.RightPanel);
            app.TotalNumCellsEditFieldLabel.Text = 'Total Num. Cells';
            app.TotalNumCellsEditField = uieditfield(app.RightPanel, 'numeric');
            app.TotalNumCellsEditField.Editable = 'off';
            app.AddNewCellButton = uibutton(app.RightPanel, 'push');
            app.AddNewCellButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewCellButtonPushed, true);
            app.AddNewCellButton.Text = 'Add New Cell';
            app.EnlargeFindCellsResultButton = uibutton(app.RightPanel, 'push');
            app.EnlargeFindCellsResultButton.ButtonPushedFcn = createCallbackFcn(app, @EnlargeFindCellsResultButtonPushed, true);
            app.EnlargeFindCellsResultButton.Text = 'Enlarge FindCells Result';
            app.EnlargeApprovedCellsButton = uibutton(app.RightPanel, 'push');
            app.EnlargeApprovedCellsButton.ButtonPushedFcn = createCallbackFcn(app, @EnlargeApprovedCellsButtonPushed, true);
            app.EnlargeApprovedCellsButton.Text = 'Enlarge Approved Cells';
            app.GotoCellButton = uibutton(app.RightPanel, 'push');
            app.GotoCellButton.ButtonPushedFcn = createCallbackFcn(app, @GotoCellButtonPushed, true);
            app.GotoCellButton.Text = 'Go to Cell';
            app.ShowExistingCheckBox = uicheckbox(app.RightPanel);
            app.ShowExistingCheckBox.Text = 'Show Existing';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spineLabeler(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end