classdef cellmasker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SmoothnessEditFieldLabel      matlab.ui.control.Label
        SmoothnessEditField           matlab.ui.control.NumericEditField
        FindCellsParametersLabel      matlab.ui.control.Label
        MaxLengthpixEditFieldLabel    matlab.ui.control.Label
        MaxLengthpixEditField         matlab.ui.control.NumericEditField
        MinLengthpixEditFieldLabel    matlab.ui.control.Label
        MinLengthpixEditField         matlab.ui.control.NumericEditField
        BrightnessThresholdEditFieldLabel  matlab.ui.control.Label
        BrightnessThresholdEditField  matlab.ui.control.NumericEditField
        FindCellsButton               matlab.ui.control.Button
        DeleteCurrentCell             matlab.ui.control.Button
        ApproveCurrentCell            matlab.ui.control.Button
        ExpansionSizeEditFieldLabel   matlab.ui.control.Label
        ExpansionSizeEditField        matlab.ui.control.NumericEditField
        DoExpansionButton             matlab.ui.control.Button
        fsmoothEditFieldLabel         matlab.ui.control.Label
        fsmoothEditField              matlab.ui.control.NumericEditField
        MeshStepEditFieldLabel        matlab.ui.control.Label
        MeshStepEditField             matlab.ui.control.NumericEditField
        MeshWidthEditFieldLabel       matlab.ui.control.Label
        MeshWidthEditField            matlab.ui.control.NumericEditField
        MeshTolEditFieldLabel         matlab.ui.control.Label
        MeshTolEditField              matlab.ui.control.NumericEditField
        AutomaticRefinementButton     matlab.ui.control.Button
        RefineManuallyButton          matlab.ui.control.Button
        BusyLampLabel                 matlab.ui.control.Label
        BusyLamp                      matlab.ui.control.Lamp
        SubsampleEditFieldLabel       matlab.ui.control.Label
        SubsampleEditField            matlab.ui.control.NumericEditField
        ExpandCropButton              matlab.ui.control.Button
        CurrentCellAxes               matlab.ui.control.UIAxes
        CenterPanel                   matlab.ui.container.Panel
        MainImageAxes                 matlab.ui.control.UIAxes
        ApprovedImageAxes             matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        DeleteButton_2                matlab.ui.control.Button
        AddNewCellButton              matlab.ui.control.Button
        ApprovedCellListListBoxLabel  matlab.ui.control.Label
        ApprovedCellListListBox       matlab.ui.control.ListBox
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        Image = []; % main image
        Mask = [];  % cell mask (1 => cells)
        FoundCellList = {};     % list generated by `findcells`
        ApprovedCellList = [];  % list of approved cells
        LabeledCellMask = []; % Description
        CurrentCellData = struct ('id', nan, ...
                'crop_box', nan(1,4), ...
                'msk_crop', [], ...
                'contour', nan(2,1)); % Description
    end
    
    methods (Access = private)
        
        function drawImage(app, plot_boundary)
            if nargin == 1 
                plot_boundary = true;
            end
            % show image and mask boundaries
            imagesc (app.MainImageAxes, app.Image);
            colormap (app.MainImageAxes, 'gray');
            set (app.MainImageAxes, 'XLimSpec', 'Tight')
            set (app.MainImageAxes, 'YLimSpec', 'Tight')
            if plot_boundary
                % show mask boundaries
                B = bwboundaries (app.Mask);
                hold (app.MainImageAxes, 'on')
                for ib = 1:numel(B)
                    bound = B{ib};
                    plot (app.MainImageAxes, bound(:,2), bound(:,1), 'k', ...
                        'LineWidth', 1)
                end
                hold (app.MainImageAxes, 'off')
            end
        end
        
        function clearApprovedCells(app)
            app.ApprovedCellList = [];
            imagesc (app.ApprovedImageAxes, app.Image);
            colormap (app.ApprovedImageAxes, "gray")
            set (app.ApprovedImageAxes, 'XLimSpec', 'Tight')
            set (app.ApprovedImageAxes, 'YLimSpec', 'Tight')
            app.ApprovedCellListListBox.Items = {};
            app.ApprovedCellListListBox.ItemsData = [];
        end
        
        function loadNewCurrentCell (app, id)
            % figure out cell ends
            xse = app.FoundCellList(id).pix([1 end], 1);
            yse = app.FoundCellList(id).pix([1 end], 2);
            % crop image and display
            crop_box = cropBoxFromCellEnds (xse, yse, 15);
            % crop label mask and display boundary
            msk_crop = imcrop (app.LabeledCellMask, crop_box);
            msk_crop = msk_crop == id;
            contour_rc = cellmask2contour (msk_crop, -1);
            contour = rc2xy (size (msk_crop), contour_rc);
            app.CurrentCellData = struct ('id', id, ...
                'crop_box', crop_box, ...
                'msk_crop', msk_crop, ...
                'contour', contour);
            app.drawCurrentCell();
        end
        
        function drawCurrentCell (app)
            % draw the cell image
            crop_box = app.CurrentCellData.crop_box;
            imagesc (app.CurrentCellAxes, imcrop (app.Image, crop_box));
            colormap (app.CurrentCellAxes, 'gray');
            set (app.CurrentCellAxes, 'XLimSpec', 'Tight')
            set (app.CurrentCellAxes, 'YLimSpec', 'Tight')
            % plot the contour
            contour = app.CurrentCellData.contour;
            hold (app.CurrentCellAxes, 'on')
            plot (app.CurrentCellAxes, contour(:,1), contour(:,2), 'b')
            hold (app.CurrentCellAxes, 'off');
        end
        
        function setBusyLamp (app, is_busy)
            if is_busy
                app.BusyLamp.Color = [0 1 0];
            else
                app.BusyLamp.Color = [0.86 0.86 0.86];
            end
            drawnow;
        end
        
        function drawApprovedCells(app, is_add)
            if not (is_add)
                % show image
                imagesc (app.ApprovedImageAxes, app.Image);
                colormap (app.ApprovedImageAxes, 'gray');
                set (app.ApprovedImageAxes, 'XLimSpec', 'Tight')
                set (app.ApprovedImageAxes, 'YLimSpec', 'Tight')
                % plot cell contours
                hold (app.ApprovedImageAxes, 'on')
                for idx = 1:numel(app.ApprovedCellList)
                    this = app.ApprovedCellList(idx);
                    contour_glb = crop2globalCoords (this.contour, this.crop_box);
                    plot (app.ApprovedImageAxes, ...
                        contour_glb(:,1), contour_glb(:,2))
                end
                hold (app.ApprovedImageAxes, 'off')
            else
                this = app.ApprovedCellList(end);
                contour_glb = crop2globalCoords (this.contour, this.crop_box);
                hold (app.ApprovedImageAxes, 'on')
                plot (app.ApprovedImageAxes, ...
                        contour_glb(:,1), contour_glb(:,2))
                hold (app.ApprovedImageAxes, 'off')
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, img, mask)
            
            % input validation
            assert (length (size (img)) == 2, 'img must be 2d array')
            assert (all (size (img) == size (mask)), ...
                'input image and mask must be same size')
            if not (isa (img, 'double'))
                img = im2double (img);
            end
            if not (islogical (mask))
               mask = not (mask == 0);
            end
            app.Image = normalizeImage (img);
            app.Mask = mask;
            % draw image
            app.drawImage (true);
            app.setBusyLamp (false);
        end

        % Button pushed function: FindCellsButton
        function FindCellsButtonPushed(app, event)
            % make sure user wants to proceed...
            if not (isempty (app.ApprovedCellList))
                answer = questdlg ("Re-finding cells will clear approved cells. Proceed?", ...
                    "Restart approval?", "yes", "no", "no");
                if strcmp (answer, "no") % they don't
                    return
                end
            end

            % if we got here, we're good to go
            app.clearApprovedCells();
            
            app.setBusyLamp(true);
            % run findcells and plot answer
            cellz = findcells (app.Image, not (app.Mask), ...
                app.SmoothnessEditField.Value, ...
                app.MaxLengthpixEditField.Value, ...
                app.MinLengthpixEditField.Value, ...
                app.BrightnessThresholdEditField.Value);
            app.drawImage (false);
            hold (app.MainImageAxes, 'on')
            for ic = 1:numel(cellz)
                plot (app.MainImageAxes, cellz(ic).pix(:,1), ...
                    cellz(ic).pix(:,2))
            end
            hold (app.MainImageAxes, 'off')
            
            app.FoundCellList = cellz;
            app.setBusyLamp(false);
        end

        % Button pushed function: DoExpansionButton
        function DoExpansionButtonPushed(app, event)
            if (app.ExpansionSizeEditField.Value) <= 0
                warndlg ('expansion size must be >= 0, try again')
                return
            end
            if (isempty (app.FoundCellList))
                warndlg ('no cells to expand, run findcells first')
                return
            end
            app.setBusyLamp(true);
            [lbl_mask, cellz] = cells2mask (app.FoundCellList, size (app.Image), ...
                true, app.ExpansionSizeEditField.Value);
            app.FoundCellList = cellz;
            app.LabeledCellMask = lbl_mask;
            % load first cell
            app.loadNewCurrentCell (1);
            app.setBusyLamp(false);
        end

        % Button pushed function: AutomaticRefinementButton
        function AutomaticRefinementButtonPushed(app, event)
            % get current cell
            if isnan (app.CurrentCellData.id)
                warndlg ('no cell to refine')
                return
            end
            app.setBusyLamp(true);
            % recalculate
            new_contour = cellmask2contour (app.CurrentCellData.msk_crop, ...
                app.fsmoothEditField.Value, app.MeshStepEditField.Value, ...
                app.MeshTolEditField.Value, app.MeshWidthEditField.Value);
            if isempty (new_contour)
                warndlg ('automatic refinement failed')
                return
            end
            app.CurrentCellData.contour = new_contour;
            % replot
            app.drawCurrentCell();
            app.setBusyLamp(false);
        end

        % Button pushed function: RefineManuallyButton
        function RefineManuallyButtonPushed(app, event)
            if isnan (app.CurrentCellData.id)
                warndlg ('no cell to refine')
                return
            end
            % show big image to do adjustment in
            fig = figure(1); 
            imagesc (imcrop (app.Image, app.CurrentCellData.crop_box));
            colormap (fig.CurrentAxes, 'gray')
            
            % draw polygon and wait for user to double-click to confirm
            skip = app.SubsampleEditField.Value;
            if (skip > 0)
                contour = app.CurrentCellData.contour(1:skip:end,:);
            else
                contour = app.CurrentCellData.contour;
            end
            h = drawpolygon (fig.CurrentAxes, 'Position', contour);
            function clickCallback (~, evt)
                if strcmp (evt.SelectionType, 'double')
                    uiresume;
                end
            end
            function [pos] = customWait (hROI)
                list = addlistener (hROI, 'ROIClicked', @clickCallback);
                uiwait;
                delete (list);
                pos = hROI.Position;
            end
            roi = customWait (h);
            
            % convert to row/col & draw
            app.CurrentCellData.contour = roi;
            app.drawCurrentCell();
            close (fig);
        end

        % Button pushed function: ApproveCurrentCell
        function ApproveCurrentCellButtonPushed(app, event)
            % add to list of approved cells
            app.ApprovedCellList = vertcat (app.ApprovedCellList, ...
                app.CurrentCellData);
            namez = arrayfun (@(x) sprintf ('Cell %d', x.id), ...
                app.ApprovedCellList, 'UniformOutput', false);
            app.ApprovedCellListListBox.Items = namez;
            app.ApprovedCellListListBox.ItemsData = arrayfun (@(x) x.id, ...
                app.ApprovedCellList);
            % increment to next cell
            app.loadNewCurrentCell (app.CurrentCellData.id+1);
            app.drawApprovedCells(numel(app.ApprovedCellList)>1);
        end

        % Button pushed function: DeleteCurrentCell
        function DeleteCurrentCellButtonPushed(app, event)
            app.loadNewCurrentCell (app.CurrentCellData.id+1);
        end

        % Button pushed function: ExpandCropButton
        function ExpandCropButtonPushed(app, event)
            answer = inputdlg ('Enter amount to increase crop padding by', ...
                'Increase Crop Pad', [1 20], {'5'});
            pad = str2double (answer{1});
            curr_box = app.CurrentCellData.crop_box;
            new_box = curr_box;
            new_box(1) = max (curr_box(1)-pad, 0);
            new_box(2) = max (curr_box(2)-pad, 0);
            new_box(3) = curr_box(3) + pad*2;
            new_box(4) = curr_box(4) + pad*2;
            app.CurrentCellData.crop_box = new_box;
            app.drawCurrentCell();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {571, 571, 571};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {571, 571};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {313, '1x', 183};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SmoothnessEditFieldLabel = uilabel(app.LeftPanel);
            app.SmoothnessEditFieldLabel.Text = 'Smoothness';
            app.SmoothnessEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SmoothnessEditField.Value = 5;
            app.FindCellsParametersLabel = uilabel(app.LeftPanel);
            app.FindCellsParametersLabel.Text = 'FindCells Parameters';
            app.MaxLengthpixEditFieldLabel = uilabel(app.LeftPanel);
            app.MaxLengthpixEditFieldLabel.Text = 'Max. Length (pix)';
            app.MaxLengthpixEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MaxLengthpixEditField.Value = 80;
            app.MinLengthpixEditFieldLabel = uilabel(app.LeftPanel);
            app.MinLengthpixEditFieldLabel.Text = 'Min. Length (pix)';
            app.MinLengthpixEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MinLengthpixEditField.Value = 10;
            app.BrightnessThresholdEditFieldLabel = uilabel(app.LeftPanel);
            app.BrightnessThresholdEditFieldLabel.Text = 'Brightness Threshold';
            app.BrightnessThresholdEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BrightnessThresholdEditField.Value = 0.5;
            app.FindCellsButton = uibutton(app.LeftPanel, 'push');
            app.FindCellsButton.ButtonPushedFcn = createCallbackFcn(app, @FindCellsButtonPushed, true);
            app.FindCellsButton.Text = 'Find Cells';
            app.DeleteCurrentCell = uibutton(app.LeftPanel, 'push');
            app.DeleteCurrentCell.ButtonPushedFcn = createCallbackFcn(app, @DeleteCurrentCellButtonPushed, true);
            app.DeleteCurrentCell.Text = 'Next';
            app.ApproveCurrentCell = uibutton(app.LeftPanel, 'push');
            app.ApproveCurrentCell.ButtonPushedFcn = createCallbackFcn(app, @ApproveCurrentCellButtonPushed, true);
            app.ApproveCurrentCell.Text = 'Approve';
            app.ExpansionSizeEditFieldLabel = uilabel(app.LeftPanel);
            app.ExpansionSizeEditFieldLabel.Text = 'Expansion Size';
            app.ExpansionSizeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ExpansionSizeEditField.Value = 3;
            app.DoExpansionButton = uibutton(app.LeftPanel, 'push');
            app.DoExpansionButton.ButtonPushedFcn = createCallbackFcn(app, @DoExpansionButtonPushed, true);
            app.DoExpansionButton.Text = 'Do Expansion';
            app.fsmoothEditFieldLabel = uilabel(app.LeftPanel);
            app.fsmoothEditFieldLabel.Text = 'fsmooth';
            app.fsmoothEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MeshStepEditFieldLabel = uilabel(app.LeftPanel);
            app.MeshStepEditFieldLabel.Text = 'Mesh Step';
            app.MeshStepEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MeshStepEditField.Value = 1;
            app.MeshWidthEditFieldLabel = uilabel(app.LeftPanel);
            app.MeshWidthEditFieldLabel.Text = 'Mesh Width';
            app.MeshWidthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MeshWidthEditField.Value = 2;
            app.MeshTolEditFieldLabel = uilabel(app.LeftPanel);
            app.MeshTolEditFieldLabel.Text = 'Mesh Tol.';
            app.MeshTolEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MeshTolEditField.Value = 0.1;
            app.AutomaticRefinementButton = uibutton(app.LeftPanel, 'push');
            app.AutomaticRefinementButton.ButtonPushedFcn = createCallbackFcn(app, @AutomaticRefinementButtonPushed, true);
            app.AutomaticRefinementButton.Text = 'Automatic Refinement';
            app.RefineManuallyButton = uibutton(app.LeftPanel, 'push');
            app.RefineManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @RefineManuallyButtonPushed, true);
            app.RefineManuallyButton.Text = 'Refine Manually';
            app.BusyLampLabel = uilabel(app.LeftPanel);
            app.BusyLampLabel.Text = 'Busy';
            app.BusyLamp = uilamp(app.LeftPanel);
            app.SubsampleEditFieldLabel = uilabel(app.LeftPanel);
            app.SubsampleEditFieldLabel.Text = 'Subsample';
            app.SubsampleEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SubsampleEditField.Limits = [1 Inf];
            app.SubsampleEditField.Value = 2;
            app.ExpandCropButton = uibutton(app.LeftPanel, 'push');
            app.ExpandCropButton.ButtonPushedFcn = createCallbackFcn(app, @ExpandCropButtonPushed, true);
            app.ExpandCropButton.Text = 'Expand Crop';
            app.CurrentCellAxes = uiaxes(app.LeftPanel);
            title(app.CurrentCellAxes, 'Current Cell')
            app.CurrentCellAxes.XTick = [];
            app.CurrentCellAxes.YTick = [];
            app.CurrentCellAxes.ZTick = [];
            app.CenterPanel = uipanel(app.GridLayout);
            app.MainImageAxes = uiaxes(app.CenterPanel);
            title(app.MainImageAxes, 'Original Image')
            app.MainImageAxes.XTick = [];
            app.MainImageAxes.YTick = [];
            app.ApprovedImageAxes = uiaxes(app.CenterPanel);
            title(app.ApprovedImageAxes, 'Approved Cells')
            app.ApprovedImageAxes.XTick = [];
            app.ApprovedImageAxes.YTick = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.DeleteButton_2 = uibutton(app.RightPanel, 'push');
            app.DeleteButton_2.Text = 'Delete';
            app.AddNewCellButton = uibutton(app.RightPanel, 'push');
            app.AddNewCellButton.Text = 'Add New Cell';
            app.ApprovedCellListListBoxLabel = uilabel(app.RightPanel);
            app.ApprovedCellListListBoxLabel.Text = 'Approved Cell List';
            app.ApprovedCellListListBox = uilistbox(app.RightPanel);
            app.ApprovedCellListListBox.Items = {};
            app.ApprovedCellListListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cellmasker(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end